classdef GUI_exercise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        LineButton   matlab.ui.control.Button
        sineButton   matlab.ui.control.Button
        DplotButton  matlab.ui.control.Button
        closeButton  matlab.ui.control.Button
    end


    properties (Access = public)
    end


    methods (Access = private)

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            x=-2*pi:0.1*pi:2*pi;
            plot(app.UIAxes,x,x,'r-o')
            axis(app.UIAxes,'tight');
        end

        % Button pushed function: sineButton
        function sineButtonPushed(app, event)
            x=-2*pi:0.1*pi:2*pi;
            plot(app.UIAxes,x,sin(x),'g-o')
            axis(app.UIAxes,'tight');
        end

        % Button pushed function: DplotButton
        function DplotButtonPushed(app, event)
            t = 0:pi/50:10*pi;
            st = sin(t);
            ct = cos(t);
            plot3(app.UIAxes,st,ct,t,'b-o')
            axis(app.UIAxes,'tight');
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            delete(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            x=-1:0.001*pi:changingValue;
            plot(app.UIAxes,x,sin(10*x),'r-o') 
            axis(app.UIAxes,[-1 1 -1 1]);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-1 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.LineButton = uibutton(app.UIFigure, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = 'Line';
            app.sineButton = uibutton(app.UIFigure, 'push');
            app.sineButton.ButtonPushedFcn = createCallbackFcn(app, @sineButtonPushed, true);
            app.sineButton.Text = 'sine';
            app.DplotButton = uibutton(app.UIFigure, 'push');
            app.DplotButton.ButtonPushedFcn = createCallbackFcn(app, @DplotButtonPushed, true);
            app.DplotButton.Text = '3D plot';
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_exercise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end