classdef GUI_temp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
        Button_2  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x=0:0.1*pi:2*pi;
            y=sin(x);
            plot(app.UIAxes,x,y,'b-p');
            cla(app.UIAxes2);
            set(app.Button,'BackgroundColor',[0.5,0.5,0.5]);
            set(app.Button_2,'BackgroundColor',[1,1,1]);
            grid(app.UIAxes,'on')
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            x=0:0.1*pi:2*pi;
            y=cos(x);
            plot(app.UIAxes2,x,y,'r-o');
            cla(app.UIAxes);
            set(app.Button_2,'BackgroundColor',[0.5,0.5,0.5]);
            set(app.Button,'BackgroundColor',[1,1,1]);
            grid(app.UIAxes2,'on')            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '正弦';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '余弦';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_temp2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end